classdef FLWAnimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        PlayButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PlaySpeedDropDownLabel        matlab.ui.control.Label
        PlaySpeedDropDown             matlab.ui.control.DropDown
        NextFrameButton               matlab.ui.control.Button
        PreviousFrameButton           matlab.ui.control.Button
        FrameIntervalsEditFieldLabel  matlab.ui.control.Label
        FrameIntervalsEditField       matlab.ui.control.NumericEditField
        CameraFollowCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        X_states 
        T_length
        T
        StopPlay = 0;
        PlaySpeed = 1;
        index = 1;
        FrameInterval = 100;
        CameraFollow = 1;
        
        ax
        h1
        h2
        h3
        h4
        h5
        
        TO1
        TO2
        TO3
        TO4
        TO5
        TO6
    end
    
    
    methods (Access = public)
        
        function Initializaztion(app,X_states,T_states)
            app.X_states = X_states; % X stores extends horizontally as T increase
            app.T = T_states;
            app.T_length = size(app.T,2);
            app.Slider.Limits = [0.1 app.T_length];
            app.PlaySpeedDropDown.ItemsData = [1, 1/2, 1/4, 1/8, 1/16, 1/32, 1/64, 1/128];
            app.CameraFollowCheckBox.Value = app.CameraFollow;
            app.FrameIntervalsEditField.Value = app.FrameInterval*0.0005;
            NewPlot(app,app.X_states(:,1));
        end
        
        function UpdatePlot(app,x)
            [TorsoCoordinate,LeftLegCoordinate, RightLegCoordinate] = GetFLWPlotCoordinate(x,0);
            p_com = p_COM(x(1:7));
            set(app.h1,'XData',TorsoCoordinate(1,:),'YData',TorsoCoordinate(2,:),'ZData',TorsoCoordinate(3,:));
            set(app.h2,'XData',LeftLegCoordinate(1,:),'YData',LeftLegCoordinate(2,:),'ZData',LeftLegCoordinate(3,:));
            set(app.h3,'XData',RightLegCoordinate(1,:),'YData',RightLegCoordinate(2,:),'ZData',RightLegCoordinate(3,:));
            set(app.h4,'XData',p_com(1),'YData',p_com(2),'ZData',p_com(3));
            if app.CameraFollow == 1
                axis([x(1)-1, x(1)+1, -1, +1, -0.5, 2]);
            end
            drawnow;
        end
        
        function NewPlot(app,x)
            linkwidth = 2;
            [TorsoCoordinate,LeftLegCoordinate, RightLegCoordinate] = GetFLWPlotCoordinate(x,0);
            p_com = p_COM(x(1:7));
            figure(1000)
            cla;
            hold on;
            % Leg
            app.h1 = plot3(TorsoCoordinate(1,:),TorsoCoordinate(2,:),TorsoCoordinate(3,:),'Color','k','LineWidth',linkwidth);
            app.h2 = plot3(LeftLegCoordinate(1,:),LeftLegCoordinate(2,:),LeftLegCoordinate(3,:),'Color','r','LineWidth',linkwidth);
            app.h3 = plot3(RightLegCoordinate(1,:),RightLegCoordinate(2,:),RightLegCoordinate(3,:),'Color','b','LineWidth',linkwidth);
            app.h4 = scatter3(p_com(1),p_com(2),p_com(3),'k*');
            % ground
            ground_size = 100;
            plot([-ground_size, ground_size],zeros(1,2));
            hold off;
            axis equal
            axis([x(1)-1, x(1)+1, -1, +1, -0.5, 2]);
            grid on
            view([0,-1,0])
            app.ax = gca;
        end
        
        function UpdateGUI(app,index)
            app.TimeEditField.Value = app.T(index);
            app.Slider.Value = index;
        end
        
        function index = GetIndexFromTime(app,t)
            t = median([t,app.T(1),app.T(end)]);
            percent = (t-app.T(1)) /(app.T(end)-app.T(1));
            index = ceil(percent*app.T_length);
        end
        
        function Record_Video(app,speed,path)
            % 50 frame per second, 0.02 second per frame assume interval of data is 0.0005, then 40*speed data per frame
            figure(1000)
            f = gcf;
            f.Position(1:2) = [100 100];
            f.Position(3:4) = [1000 1000];
            F = [];
            total_frames = floor(app.T_length/(40*speed))
            for i = 1:total_frames
                UpdatePlot(app,app.X_states(:,max(1,floor(i*40*speed))))
                F = [F getframe(gcf)];
            end

            clk = clock;
            date_str = [num2str(clk(1)),'-',num2str(clk(2)),'-',num2str(clk(3))];
            time_str = [num2str(clk(4)),'-',num2str(clk(5))];
            save_path = [path,'/Video/',date_str,'/',time_str,'/'];
            mkdir(save_path)

            
            % create the video writer with 1 fps
            writerObj = VideoWriter([save_path '/myVideo.mp4'],'MPEG-4');
            writerObj.FrameRate = 50;
            % set the seconds per image
            % open the video writer
            open(writerObj);
            % write the frames to the video
            for i=1:length(F)
                % convert the image to a frame
                frame = F(i) ;
                writeVideo(writerObj, frame);
            end
            % close the writer object
            close(writerObj);
        end
    end
    

    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            %             index = ceil(changingValue*app.T_length);
            %             index = median([index,1,app.T_length]);
            app.index = ceil(changingValue);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            app.index = GetIndexFromTime(app,value);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            tic;
            sim_previous_time = app.TimeEditField.Value;
            sim_current_time = 0;
            real_current_time = 0;
            real_previous_time = 0;
            real_time_interval = 0;
            start_time = app.TimeEditField.Value;
            app.StopPlay = 0;
            while sim_current_time <app.T(end) && app.StopPlay == 0;
                real_current_time = toc;
                real_time_interval = real_current_time - real_previous_time;
                real_previous_time = real_current_time;
                sim_current_time = sim_previous_time + real_time_interval*app.PlaySpeed;
                sim_previous_time = sim_current_time;
                app.index = app.GetIndexFromTime(sim_current_time);
                UpdatePlot(app,app.X_states(:,app.index));
                UpdateGUI(app,app.index);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopPlay = 1;
        end

        % Value changed function: PlaySpeedDropDown
        function PlaySpeedDropDownValueChanged(app, event)
            value = app.PlaySpeedDropDown.Value;
            app.PlaySpeed = value;
        end

        % Value changed function: FrameIntervalsEditField
        function FrameIntervalsEditFieldValueChanged(app, event)
            value = app.FrameIntervalsEditField.Value;
            app.FrameInterval = floor(value/0.0005);
            app.FrameIntervalsEditField.Value = app.FrameInterval*0.0005;
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            app.index = app.index - app.FrameInterval;
            app.index = max([app.index,1]);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.index = app.index + app.FrameInterval;
            app.index = min([app.index,app.T_length]);
            UpdatePlot(app,app.X_states(:,app.index));
            UpdateGUI(app,app.index);
            app.StopPlay = 1;
        end

        % Value changed function: CameraFollowCheckBox
        function CameraFollowCheckBoxValueChanged(app, event)
            value = app.CameraFollowCheckBox.Value;
            app.CameraFollow = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.4f';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlaySpeedDropDownLabel = uilabel(app.UIFigure);
            app.PlaySpeedDropDownLabel.Text = 'PlaySpeed';
            app.PlaySpeedDropDown = uidropdown(app.UIFigure);
            app.PlaySpeedDropDown.Items = {'1', '1/2', '1/4', '1/8', '1/16', '1/32', '1/64', '1/128'};
            app.PlaySpeedDropDown.ItemsData = {'1', '1/2', '1/4', '1/8', '1/16', '1/32', '1/64', '1/128'};
            app.PlaySpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaySpeedDropDownValueChanged, true);
            app.PlaySpeedDropDown.Value = '1';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.PreviousFrameButton = uibutton(app.UIFigure, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.FrameIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.FrameIntervalsEditFieldLabel.Text = 'Frame Interval (s)';
            app.FrameIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @FrameIntervalsEditFieldValueChanged, true);
            app.FrameIntervalsEditField.Value = 0.0005;
            app.CameraFollowCheckBox = uicheckbox(app.UIFigure);
            app.CameraFollowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameraFollowCheckBoxValueChanged, true);
            app.CameraFollowCheckBox.Text = 'CameraFollow';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FLWAnimation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end